classdef covidapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        CountryListBoxLabel         matlab.ui.control.Label
        CountryListBox              matlab.ui.control.ListBox
        RegionListBoxLabel          matlab.ui.control.Label
        RegionListBox               matlab.ui.control.ListBox
        AveragingofdaysSliderLabel  matlab.ui.control.Label
        AveragingofdaysSlider       matlab.ui.control.Slider
        DatatoplotButtonGroup       matlab.ui.container.ButtonGroup
        CasesButton                 matlab.ui.control.RadioButton
        DeathsButton                matlab.ui.control.RadioButton
        BothButton                  matlab.ui.control.RadioButton
        OptionButtonGroup           matlab.ui.container.ButtonGroup
        CumulativeButton            matlab.ui.control.RadioButton
        DailyButton                 matlab.ui.control.RadioButton
        UIAxes                      matlab.ui.control.UIAxes
    end

    properties (Access = private)
        Dates  %measurement of covid-19 (in days)
        Cases
        Deaths
        CASES
        DEATHS
        ind
        countrynames
        Avrg
    end
    
    methods (Access = private)
        function plotData(app)
            k=app.ind;
            if app.CumulativeButton.Value
                app.DEATHS=app.Deaths(k,:);
                app.CASES =app.Cases (k,:);
            end
            if app.DailyButton.Value
                app.DEATHS(1)=app.Deaths(k,1);
                app.CASES (1)=app.Cases (k,1);
                for i=284:-1:2
                    app.DEATHS(i)=app.Deaths(k,i)-app.Deaths(k,i-1);
                    app.CASES (i)=app.Cases (k,i)-app.Cases (k,i-1);
                end
            end
            v=app.Avrg; % When i make this row as v=app.AveragingofdaysSlider.Value, 
            % it acted like Value ChangED Slider, not ChangING one :')
            if v>1
                app.DEATHS=movmean(app.DEATHS,v);
                app.CASES =movmean(app.CASES ,v);
            end
            cla (app.UIAxes,"reset") % i want to reset axes because
            %  yyaxis command makes some problem
            if app.CasesButton.Value
                bar (app.UIAxes,app.Dates,app.CASES ,'b')
            end
            if app.DeathsButton.Value
                cla(app.UIAxes,"reset")
                yyaxis(app.UIAxes,"right")
                plot(app.UIAxes,app.Dates,app.DEATHS,'r')
            end
            if app.BothButton.Value
                bar (app.UIAxes,app.Dates,app.CASES ,'b',"LineWidth",1)
                hold (app.UIAxes,"on" )
                yyaxis(app.UIAxes,"right")
                plot(app.UIAxes,app.Dates,app.DEATHS,'r','LineWidth',2)
            end
            % Configuring labels:
            if ~isempty(app.RegionListBox.Value)
                t1=string(app.DatatoplotButtonGroup.SelectedObject.Text)+" in "+app.RegionListBox.Value;
                t2="/"+app.CountryListBox.Value+" as "+string(app.OptionButtonGroup.SelectedObject.Text);
                title(app.UIAxes,t1+t2)
                xlabel(app.UIAxes,"dates")
                ylabel(app.UIAxes,"Death Counts")
                yyaxis(app.UIAxes,"left");
                ylabel(app.UIAxes,"Case Counts")
                app.UIAxes.YColor=[0 0 1];
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %loading the data
            load covid_data.mat covid_data;
            %determining cases & deaths: (but at first, preallocating them...)
            app.Deaths=zeros(325,284);
            app.Cases =zeros(325,284);
            app.DEATHS=zeros(1  ,284);
            app.CASES =zeros(1  ,284);
            for r=1:325
                for c=1:284
                    app.Cases (r,c)=covid_data{r+1,c+2}(1);
                    app.Deaths(r,c)=covid_data{r+1,c+2}(2);
                end
            end;                                 clear r&c;
            app.DEATHS=sum(app.Deaths);
            app.CASES =sum(app.Cases );
            d1=datetime(2020,1,22);
            d2=datetime(2020,10,31);
            daycount=caldays(between(d1,d2,'days'))+1;
            app.Dates= d1 + caldays(1:daycount); %configuring dates to x axis
            bar (app.UIAxes,app.Dates,app.CASES ,'b',"LineWidth",0.5)
            hold (app.UIAxes,"on" )
            yyaxis(app.UIAxes,"right")
            plot(app.UIAxes,app.Dates,app.DEATHS,'r','LineWidth',2.0)
            hold (app.UIAxes,"off")
            app.countrynames=covid_data(2:end,1);
            app.Deaths(326,:)=sum(app.Deaths);
            app.Cases (326,:)=sum(app.Cases );
            app.ind=326; %starting from summition of all covid-19 data
            app.Avrg=8; %starting average from 8. 
            % Till touching to the slider, it will definitely stay in 8
        end

        % Value changed function: CountryListBox
        function CountryListBoxValueChanged(app, event)
            value=app.CountryListBox.Value;
            if value=="Australia"
                app.RegionListBox.Items={'All','Australian Capital Territory', 'New South Wales', 'Northern Territory', 'Queensland', 'South Australia', 'Tasmania', 'Victoria', 'Western Australia'};
            elseif value=="Canada"
                app.RegionListBox.Items={'All','Alberta', 'British Columbia', 'Diamond Princess', 'Grand Princess', 'Manitoba', 'New Brunswick', 'Newfoundland and Labrador', 'Northwest Territories', 'Nova Scotia', 'Ontario', 'Prince Edward Island', 'Quebec', 'Saskatchewan', 'Yukon'};
            elseif value=="China"
                app.RegionListBox.Items={'All','Anhui', 'Beijing', 'Chongqing', 'Fujian', 'Gansu', 'Guangdong', 'Guangxi', 'Guizhou', 'Hainan', 'Hebei', 'Heilongjiang', 'Henan', 'Hong Kong', 'Hubei', 'Hunan', 'Inner Mongolia', 'Jiangsu', 'Jiangxi', 'Jilin', 'Liaoning', 'Macau', 'Ningxia', 'Qinghai', 'Shaanxi', 'Shandong', 'Shanghai', 'Shanxi', 'Sichuan', 'Tianjin', 'Tibet', 'Xinjiang', 'Yunnan', 'Zhejiang'};
            elseif value=="Denmark"
                app.RegionListBox.Items={'All','Faroe Islands', 'Greenland'};
            elseif value=="France"
                app.RegionListBox.Items={'All','French Guiana', 'French Polynesia', 'Guadeloupe', 'Martinique', 'Mayotte', 'New Caledonia', 'Reunion', 'Saint Barthelemy', 'Saint Pierre and Miquelon', 'St Martin'};
            elseif value=="Netherlands"
                app.RegionListBox.Items={'All','Aruba', 'Bonaire, Sint Eustatius and Saba', 'Curacao', 'Sint Maarten'};
            elseif value=="United Kingdom"
                app.RegionListBox.Items={'All','Anguilla', 'Bermuda', 'British Virgin Islands', 'Cayman Islands', 'Channel Islands', 'Falkland Islands (Malvinas)', 'Gibraltar', 'Isle of Man', 'Montserrat', 'Turks and Caicos Islands'};
            elseif value=="United States"
                app.RegionListBox.Items={'All','Alabama', 'Alaska', 'American Samoa', 'Arizona', 'Arkansas', 'California', 'Colorado', 'Connecticut', 'Delaware', 'District of Columbia', 'Florida', 'Georgia', 'Guam', 'Hawaii', 'Idaho', 'Illinois', 'Indiana', 'Iowa', 'Kansas', 'Kentucky', 'Louisiana', 'Maine', 'Maryland', 'Massachusetts', 'Michigan', 'Minnesota', 'Mississippi', 'Missouri', 'Montana', 'Nebraska', 'Nevada', 'New Hampshire', 'New Jersey', 'New Mexico', 'New York', 'North Carolina', 'North Dakota', 'Northern Mariana Islands', 'Ohio', 'Oklahoma', 'Oregon', 'Pennsylvania', 'Puerto Rico', 'Rhode Island', 'South Carolina', 'South Dakota', 'Tennessee', 'Texas', 'Utah', 'Vermont', 'Virgin Islands', 'Virginia', 'Washington', 'West Virginia', 'Wisconsin'};
            else
                app.RegionListBox.Items="All";
            end
            [check,~]=ismember(app.CountryListBox.Value,{'France','Netherlands','United Kingdom','United States'});
            [~,location]=ismember(app.CountryListBox.Value,app.countrynames);
            if ~check;app.ind=location;end
            if value=="All"
                app.ind=326;
            end
            app.plotData()
        end

        % Selection changed function: DatatoplotButtonGroup
        function DatatoplotButtonGroupSelectionChanged(app, event)
            app.plotData()
        end

        % Selection changed function: OptionButtonGroup
        function OptionButtonGroupSelectionChanged(app, event)
            app.plotData()
        end

        % Value changed function: RegionListBox
        function RegionListBoxValueChanged(app, event)
            item=app.RegionListBox.Value;
            [check,location]=ismember(item,{'All','Australian Capital Territory', 'New South Wales', 'Northern Territory', 'Queensland', 'South Australia', 'Tasmania', 'Victoria', 'Western Australia'});
            if check;app.ind=location+9;end
            
            [check,location]=ismember(item,{'All','Alberta', 'British Columbia', 'Diamond Princess', 'Grand Princess', 'Manitoba', 'New Brunswick', 'Newfoundland and Labrador', 'Northwest Territories', 'Nova Scotia', 'Ontario', 'Prince Edward Island', 'Quebec', 'Saskatchewan', 'Yukon'});
            if check;app.ind=location+41;end
            
            [check,location]=ismember(item,{'All','Anhui', 'Beijing', 'Chongqing', 'Fujian', 'Gansu', 'Guangdong', 'Guangxi', 'Guizhou', 'Hainan', 'Hebei', 'Heilongjiang', 'Henan', 'Hong Kong', 'Hubei', 'Hunan', 'Inner Mongolia', 'Jiangsu', 'Jiangxi', 'Jilin', 'Liaoning', 'Macau', 'Ningxia', 'Qinghai', 'Shaanxi', 'Shandong', 'Shanghai', 'Shanxi', 'Sichuan', 'Tianjin', 'Tibet', 'Xinjiang', 'Yunnan', 'Zhejiang'});
            if check;app.ind=location+59;end
            
            [check,location]=ismember(item,{'All','Faroe Islands', 'Greenland'});
            if check;app.ind=location+103;end
            
            [check,location]=ismember(item,{'All','French Guiana', 'French Polynesia', 'Guadeloupe', 'Martinique', 'Mayotte', 'New Caledonia', 'Reunion', 'Saint Barthelemy', 'Saint Pierre and Miquelon', 'St Martin'});
            if check;app.ind=location+119;end
            
            [check,location]=ismember(item,{'All','Aruba', 'Bonaire, Sint Eustatius and Saba', 'Curacao', 'Sint Maarten'});
            if check;app.ind=location+190;end
            
            [check,location]=ismember(item,{'All','Anguilla', 'Bermuda', 'British Virgin Islands', 'Cayman Islands', 'Channel Islands', 'Falkland Islands (Malvinas)', 'Gibraltar', 'Isle of Man', 'Montserrat', 'Turks and Caicos Islands'});
            if check;app.ind=location+250;end
            
            [check,location]=ismember(item,{'All','Alabama', 'Alaska', 'American Samoa', 'Arizona', 'Arkansas', 'California', 'Colorado', 'Connecticut', 'Delaware', 'District of Columbia', 'Florida', 'Georgia', 'Guam', 'Hawaii', 'Idaho', 'Illinois', 'Indiana', 'Iowa', 'Kansas', 'Kentucky', 'Louisiana', 'Maine', 'Maryland', 'Massachusetts', 'Michigan', 'Minnesota', 'Mississippi', 'Missouri', 'Montana', 'Nebraska', 'Nevada', 'New Hampshire', 'New Jersey', 'New Mexico', 'New York', 'North Carolina', 'North Dakota', 'Northern Mariana Islands', 'Ohio', 'Oklahoma', 'Oregon', 'Pennsylvania', 'Puerto Rico', 'Rhode Island', 'South Carolina', 'South Dakota', 'Tennessee', 'Texas', 'Utah', 'Vermont', 'Virgin Islands', 'Virginia', 'Washington', 'West Virginia', 'Wisconsin'});
            if check;app.ind=location+261;end
            
            app.plotData()
        end

        % Value changing function: AveragingofdaysSlider
        function AveragingofdaysSliderValueChanging(app, event)
            app.Avrg=event.Value;
            app.plotData()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CountryListBoxLabel = uilabel(app.UIFigure);
            app.CountryListBoxLabel.Text = 'Country';
            app.CountryListBox = uilistbox(app.UIFigure);
            app.CountryListBox.Items = {'All', 'Afghanistan', 'Albania', 'Algeria', 'Andorra', 'Angola', 'Antigua and Barbuda', 'Argentina', 'Armenia', 'Australia', 'Austria', 'Azerbaijan', 'Bahamas', 'Bahrain', 'Bangladesh', 'Barbados', 'Belarus', 'Belgium', 'Belize', 'Benin', 'Bhutan', 'Bolivia', 'Bosnia and Herzegovina', 'Botswana', 'Brazil', 'Brunei', 'Bulgaria', 'Burkina Faso', 'Burma', 'Burundi', 'Cabo Verde', 'Cambodia', 'Cameroon', 'Canada', 'Central African Republic', 'Chad', 'Chile', 'China', 'Colombia', 'Comoros', 'Congo (Brazzaville)', 'Congo (Kinshasa)', 'Costa Rica', 'Cote d''Ivoire', 'Croatia', 'Cuba', 'Cyprus', 'Czechia', 'Denmark', 'Djibouti', 'Dominica', 'Dominican Republic', 'Ecuador', 'Egypt', 'El Salvador', 'Equatorial Guinea', 'Eritrea', 'Estonia', 'Eswatini', 'Ethiopia', 'Fiji', 'Finland', 'France', 'Gabon', 'Gambia', 'Georgia', 'Germany', 'Ghana', 'Greece', 'Grenada', 'Guatemala', 'Guinea', 'Guinea-Bissau', 'Guyana', 'Haiti', 'Holy See', 'Honduras', 'Hungary', 'Iceland', 'India', 'Indonesia', 'Iran', 'Iraq', 'Ireland', 'Israel', 'Italy', 'Jamaica', 'Japan', 'Jordan', 'Kazakhstan', 'Kenya', 'Korea, South', 'Kosovo', 'Kuwait', 'Kyrgyzstan', 'Laos', 'Latvia', 'Lebanon', 'Lesotho', 'Liberia', 'Libya', 'Liechtenstein', 'Lithuania', 'Luxembourg', 'MS Zaandam', 'Madagascar', 'Malawi', 'Malaysia', 'Maldives', 'Mali', 'Malta', 'Marshall Islands', 'Mauritania', 'Mauritius', 'Mexico', 'Moldova', 'Monaco', 'Mongolia', 'Montenegro', 'Morocco', 'Mozambique', 'Namibia', 'Nepal', 'Netherlands', 'New Zealand', 'Nicaragua', 'Niger', 'Nigeria', 'North Macedonia', 'Norway', 'Oman', 'Pakistan', 'Panama', 'Papua New Guinea', 'Paraguay', 'Peru', 'Philippines', 'Poland', 'Portugal', 'Qatar', 'Romania', 'Russia', 'Rwanda', 'Saint Kitts and Nevis', 'Saint Lucia', 'Saint Vincent and the Grenadines', 'San Marino', 'Sao Tome and Principe', 'Saudi Arabia', 'Senegal', 'Serbia', 'Seychelles', 'Sierra Leone', 'Singapore', 'Slovakia', 'Slovenia', 'Solomon Islands', 'Somalia', 'South Africa', 'South Sudan', 'Spain', 'Sri Lanka', 'Sudan', 'Suriname', 'Sweden', 'Switzerland', 'Syria', 'Taiwan*', 'Tajikistan', 'Tanzania', 'Thailand', 'Timor-Leste', 'Togo', 'Trinidad and Tobago', 'Tunisia', 'Turkey', 'Uganda', 'Ukraine', 'United Arab Emirates', 'United Kingdom', 'United States', 'Uruguay', 'Uzbekistan', 'Venezuela', 'Vietnam', 'West Bank and Gaza', 'Western Sahara', 'Yemen', 'Zambia', 'Zimbabwe'};
            app.CountryListBox.ValueChangedFcn = createCallbackFcn(app, @CountryListBoxValueChanged, true);
            app.CountryListBox.Value = 'All';
            app.RegionListBoxLabel = uilabel(app.UIFigure);
            app.RegionListBoxLabel.Text = 'Region';
            app.RegionListBox = uilistbox(app.UIFigure);
            app.RegionListBox.Items = {};
            app.RegionListBox.ValueChangedFcn = createCallbackFcn(app, @RegionListBoxValueChanged, true);
            app.RegionListBox.Value = {};
            app.AveragingofdaysSliderLabel = uilabel(app.UIFigure);
            app.AveragingofdaysSliderLabel.Text = 'Averaging of days';
            app.AveragingofdaysSlider = uislider(app.UIFigure);
            app.AveragingofdaysSlider.Limits = [1 15];
            app.AveragingofdaysSlider.ValueChangingFcn = createCallbackFcn(app, @AveragingofdaysSliderValueChanging, true);
            app.AveragingofdaysSlider.Value = 8;
            app.DatatoplotButtonGroup = uibuttongroup(app.UIFigure);
            app.DatatoplotButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DatatoplotButtonGroupSelectionChanged, true);
            app.DatatoplotButtonGroup.Title = 'Data to plot';
            app.CasesButton = uiradiobutton(app.DatatoplotButtonGroup);
            app.CasesButton.Text = 'Cases';
            app.DeathsButton = uiradiobutton(app.DatatoplotButtonGroup);
            app.DeathsButton.Text = 'Deaths';
            app.BothButton = uiradiobutton(app.DatatoplotButtonGroup);
            app.BothButton.Text = 'Both';
            app.BothButton.Value = true;
            app.OptionButtonGroup = uibuttongroup(app.UIFigure);
            app.OptionButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OptionButtonGroupSelectionChanged, true);
            app.OptionButtonGroup.Title = 'Option';
            app.CumulativeButton = uiradiobutton(app.OptionButtonGroup);
            app.CumulativeButton.Text = 'Cumulative';
            app.CumulativeButton.Value = true;
            app.DailyButton = uiradiobutton(app.OptionButtonGroup);
            app.DailyButton.Text = 'Daily';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Covid-19 number of cases or deaths')
            xlabel(app.UIAxes, 'dates')
            ylabel(app.UIAxes, 'counts')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = covidapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end